classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Numberofteethanywherebetween12to300EditFieldLabel  matlab.ui.control.Label
        Numofteeth1                     matlab.ui.control.NumericEditField
        Gear1Label                      matlab.ui.control.Label
        Gear2Label                      matlab.ui.control.Label
        Numberofteethanywherebetween12to300EditField_2Label  matlab.ui.control.Label
        Numofteeth2                     matlab.ui.control.NumericEditField
        PinionGearDesignerLabel         matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        MaterialDropDownLabel           matlab.ui.control.Label
        MaterialDropDown                matlab.ui.control.DropDown
        MaterialDropDown_2Label         matlab.ui.control.Label
        MaterialDropDown_2              matlab.ui.control.DropDown
        ToothFormDropDownLabel          matlab.ui.control.Label
        ToothFormDropDown               matlab.ui.control.DropDown
        IncludeDynamicAnalysisCheckBox  matlab.ui.control.CheckBox
        ResetButton                     matlab.ui.control.Button
        SelectDriverButtonGroup         matlab.ui.container.ButtonGroup
        Driver1Button                   matlab.ui.control.RadioButton
        Driver2Button                   matlab.ui.control.RadioButton
        rpm1label                       matlab.ui.control.Label
        rpm1                            matlab.ui.control.NumericEditField
        rpm2label                       matlab.ui.control.Label
        rpm2                            matlab.ui.control.NumericEditField
        PowerRequiredLabel              matlab.ui.control.Label
        PowerRequired                   matlab.ui.control.NumericEditField
        ExportCheckBox                  matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            numofteeth1 = app.Numofteeth1.Value;
            numofteeth2 = app.Numofteeth2.Value;
            profile = app.ToothFormDropDown.Value;
            material1 = app.MaterialDropDown.Value;
            material2 = app.MaterialDropDown_2.Value;
            power = app.PowerRequired.Value;
            
            switch app.SelectDriverButtonGroup.SelectedObject.Text
                case 'Gear 1'
                    app.rpm1label.Visible = "on";
                    app.rpm1.Visible = "on";
                    app.rpm2label.Visible = "off";
                    app.rpm2.Visible = "off";
                    rpm = app.rpm1.Value;
                    VR = numofteeth1/numofteeth2;
                case 'Gear 2'
                    app.rpm1label.Visible = "off";
                    app.rpm1.Visible = "off";
                    app.rpm2label.Visible = "on";
                    app.rpm2.Visible = "on";
                    rpm = app.rpm2.Value;
                    VR = numofteeth2/numofteeth1;
            end
            
            
            strength1 = S_o(material1);
            strength2 = S_o(material2);
                  
            if strncmp(material1, material2, 9) == 1
                
                if numofteeth1 > numofteeth2
                    y = LewisFactor(numofteeth2, profile);
                    N = numofteeth2;
                    strength = strength2;
                elseif numofteeth2 > numofteeth1
                    y = LewisFactor(numofteeth1, profile);
                    N = numofteeth1;
                    strength = strength1;
                end
                
            else                
                  y1 = LewisFactor(numofteeth1, profile);
                  y2 = LewisFactor(numofteeth2, profile);
         
                  if strength1*y1 > strength2*y2
                      y = y2;
                      N = numofteeth2;
                      strength = strength2;
                  elseif strength2*y2 > strength1*y1
                      y = y1;
                      N = numofteeth1;
                      strength = strength1;
                  end
            end
            
            M_t = (power*60*10^3)/(2*pi*rpm);
            
`            ind_s = (strength*10^6)/2;
            
            k_assum = 4;
            
            module = (((2*M_t)/(k_assum*y*pi^2*N*ind_s))^(1/3))*10^3;
            
            assum_module = ModuleSelector(module);
            
            [dia, true_module, allow_s, ind_s_true] = module_corrector(assum_module, M_t, k_assum, y, N, rpm, strength);
                                                
            k_true = k_assum*ind_s_true/allow_s;
            
            face_width = k_true*pi*true_module;
            
            if strncmp('Involute 14.5', profile, 13) == 1 || strncmp('Full Depth 20', profile, 13) == 1
                addendum = true_module;
                dedendum = 1.25*true_module;
                working_depth = 2*true_module;
                whole_depth = 2.25*true_module;
                tooth_thickness = 1.5708*true_module;
                minimum_clearance = 0.25*true_module;
                fillet_radius = 0.4*true_module;
            elseif strncmp('Stub 20', profile, 7) == 1
                addendum = 0.8*true_module;
                dedendum = true_module;
                working_depth = 1.6*true_module;
                whole_depth = 1.8*true_module;
                tooth_thickness = 1.5708*true_module;
                minimum_clearance = 0.2*true_module;
                fillet_radius = 0.4*true_module;
            end
            
            switch app.SelectDriverButtonGroup.SelectedObject.Text
                case 'Gear 1'
                    dia1 = dia;
                    dia2 = VR*dia;
                case 'Gear 2'
                    dia2 = dia;
                    dia1 = VR*dia;
            end
                
            T = table([material1; material2],...
                    [dia1 ; dia2],...
                    [true_module; true_module],...
                    [addendum; addendum],...
                    [dedendum; dedendum],...
                    [working_depth; working_depth],...
                    [whole_depth; whole_depth],...
                    [tooth_thickness; tooth_thickness],...
                    [minimum_clearance; minimum_clearance],...
                    [fillet_radius; fillet_radius;],...
                    [face_width; face_width],...
                    'VariableNames', {'Material' 'Diameter' 'Module' ...
                    'Addendum' 'Dedendum' 'Working Depth'...
                    'Whole Depth' 'Tooth Thickness'...
                    'Minium Clearance' 'Fillet Radius' 'Face Width'},... 
                    'RowNames', {'Gear 1' 'Gear 2'});
                
            if app.ExportCheckBox.Value == 1            
                filename = 'Results.xlsx';
                writetable(T, filename, 'Sheet', 1);
            else
                disp(T);
            end
            
            function [dia, true_module, allow_s, ind_s_true] = module_corrector(assum_module, M_t, k_assum, y, N, rpm, strength)

            ind_s_true = (2*M_t)/(k_assum*y*pi^2*N*((assum_module*10^-3)^3));

            dia = N*assum_module;            
            speed = (pi*dia*10^-3*rpm)/60;
            
                if speed < 10
                    allow_s = (strength*10^6)*(3/(3+speed));
                 elseif speed >= 10 && speed <= 20
                    allow_s = (strength*10^6)*(6/(6+speed));
                 elseif speed > 20
                    allow_s = (strength*10^6)*(5.6/(5.6+speed));
                end
                
                if ind_s_true < allow_s
                    true_module = assum_module;
                end             

                while ind_s_true >= allow_s 

                assum_module = assum_module + 1;
                ind_s_true = (2*M_t)/(k_assum*y*pi^2*N*((assum_module*10^-3)^3));

                dia = N*assum_module;            
                speed = (pi*dia*10^-3*rpm)/60;
            
                    if speed < 10
                        allow_s = (strength*10^6)*(3/(3+speed));
                    elseif speed >= 10 && speed <= 20
                        allow_s = (strength*10^6)*(6/(6+speed));
                    elseif speed > 20
                        allow_s = (strength*10^6)*(5.6/(5.6+speed));
                    end
                                        
                        if ind_s_true < allow_s
                            true_module = assum_module;
                            continue
                        end                        
                end
            end
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Numberofteethanywherebetween12to300EditFieldLabel = uilabel(app.UIFigure);
            app.Numberofteethanywherebetween12to300EditFieldLabel.Text = 'Number of teeth (anywhere between 12 to 300)';
            app.Numofteeth1 = uieditfield(app.UIFigure, 'numeric');
            app.Numofteeth1.Limits = [12 300];
            app.Numofteeth1.RoundFractionalValues = 'on';
            app.Numofteeth1.Value = 12;
            app.Gear1Label = uilabel(app.UIFigure);
            app.Gear1Label.Text = 'Gear 1';
            app.Gear2Label = uilabel(app.UIFigure);
            app.Gear2Label.Text = 'Gear 2';
            app.Numberofteethanywherebetween12to300EditField_2Label = uilabel(app.UIFigure);
            app.Numberofteethanywherebetween12to300EditField_2Label.Text = 'Number of teeth (anywhere between 12 to 300)';
            app.Numofteeth2 = uieditfield(app.UIFigure, 'numeric');
            app.Numofteeth2.Limits = [12 300];
            app.Numofteeth2.RoundFractionalValues = 'on';
            app.Numofteeth2.Value = 12;
            app.PinionGearDesignerLabel = uilabel(app.UIFigure);
            app.PinionGearDesignerLabel.Text = 'Pinion Gear Designer';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.MaterialDropDownLabel = uilabel(app.UIFigure);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.UIFigure);
            app.MaterialDropDown.Items = {'Steel', 'Cast Iron', 'Bronze', ''};
            app.MaterialDropDown.Value = 'Steel';
            app.MaterialDropDown_2Label = uilabel(app.UIFigure);
            app.MaterialDropDown_2Label.Text = 'Material';
            app.MaterialDropDown_2 = uidropdown(app.UIFigure);
            app.MaterialDropDown_2.Items = {'Steel', 'Cast Iron', 'Bronze', ''};
            app.MaterialDropDown_2.Value = 'Steel';
            app.ToothFormDropDownLabel = uilabel(app.UIFigure);
            app.ToothFormDropDownLabel.Text = 'Tooth Form';
            app.ToothFormDropDown = uidropdown(app.UIFigure);
            app.ToothFormDropDown.Items = {'Involute 14.5', 'Full Depth 20', 'Stub 20'};
            app.ToothFormDropDown.Value = 'Involute 14.5';
            app.IncludeDynamicAnalysisCheckBox = uicheckbox(app.UIFigure);
            app.IncludeDynamicAnalysisCheckBox.Text = 'Include Dynamic Analysis';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.Text = 'Reset';
            app.SelectDriverButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectDriverButtonGroup.Title = 'Select Driver';
            app.Driver1Button = uiradiobutton(app.SelectDriverButtonGroup);
            app.Driver1Button.Text = 'Gear 1';
            app.Driver1Button.Value = true;
            app.Driver2Button = uiradiobutton(app.SelectDriverButtonGroup);
            app.Driver2Button.Text = 'Gear 2';
            app.rpm1label = uilabel(app.UIFigure);
            app.rpm1label.Text = 'Revolution Per Minute';
            app.rpm1 = uieditfield(app.UIFigure, 'numeric');
            app.rpm1.Limits = [1 Inf];
            app.rpm1.Value = 1;
            app.rpm2label = uilabel(app.UIFigure);
            app.rpm2label.Text = 'Revolution Per Minute';
            app.rpm2 = uieditfield(app.UIFigure, 'numeric');
            app.rpm2.Limits = [1 Inf];
            app.rpm2.Value = 1;
            app.PowerRequiredLabel = uilabel(app.UIFigure);
            app.PowerRequiredLabel.Text = 'Power Required (in kW)';
            app.PowerRequired = uieditfield(app.UIFigure, 'numeric');
            app.PowerRequired.Limits = [1 Inf];
            app.PowerRequired.Value = 1;
            app.ExportCheckBox = uicheckbox(app.UIFigure);
            app.ExportCheckBox.Text = 'Export the results into an Excel file';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end